classdef PLC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        ProximalThoracicSpinnerLabel    matlab.ui.control.Label
        ProximalThoracicSpinner         matlab.ui.control.Spinner
        MainThoracicSpinnerLabel        matlab.ui.control.Label
        MainThoracicSpinner             matlab.ui.control.Spinner
        ThoracolumbarlumbarSpinnerLabel  matlab.ui.control.Label
        ThoracolumbarlumbarSpinner      matlab.ui.control.Spinner
        ProximalThoracicSpinner_2Label  matlab.ui.control.Label
        ProximalThoracicSpinner_2       matlab.ui.control.Spinner
        MainThoracicSpinner_2Label      matlab.ui.control.Label
        MainThoracicSpinner_2           matlab.ui.control.Spinner
        ThoracolumbarlumbarSpinner_2Label  matlab.ui.control.Label
        ThoracolumbarlumbarSpinner_2    matlab.ui.control.Spinner
        T2T5SpinnerLabel                matlab.ui.control.Label
        T2T5Spinner                     matlab.ui.control.Spinner
        T5T12SpinnerLabel               matlab.ui.control.Label
        T5T12Spinner                    matlab.ui.control.Spinner
        T10L2SpinnerLabel               matlab.ui.control.Label
        T10L2Spinner                    matlab.ui.control.Spinner
        ProbabilisticLenkeClassificationPLCLabel  matlab.ui.control.Label
        CobbAnglesLabel                 matlab.ui.control.Label
        BendingFilmLabel                matlab.ui.control.Label
        KyphosisLabel                   matlab.ui.control.Label
        EnterButton                     matlab.ui.control.Button
        NoteTextAreaLabel               matlab.ui.control.Label
        NoteTextArea                    matlab.ui.control.TextArea
        InterobserverStandardDeviationLabel  matlab.ui.control.Label
        CoronalAnglesSpinnerLabel       matlab.ui.control.Label
        CoronalAnglesSpinner            matlab.ui.control.Spinner
        SagittalAnglesSpinnerLabel      matlab.ui.control.Label
        SagittalAnglesSpinner           matlab.ui.control.Spinner
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: CoronalAnglesSpinner, 
        % CoronalAnglesSpinner, EnterButton, MainThoracicSpinner, 
        % MainThoracicSpinner, MainThoracicSpinner_2, 
        % MainThoracicSpinner_2, ProximalThoracicSpinner, 
        % ProximalThoracicSpinner, ProximalThoracicSpinner_2, 
        % ProximalThoracicSpinner_2, SagittalAnglesSpinner, 
        % SagittalAnglesSpinner, T10L2Spinner, T10L2Spinner, 
        % T2T5Spinner, T2T5Spinner, T5T12Spinner, T5T12Spinner, 
        % ThoracolumbarlumbarSpinner, ThoracolumbarlumbarSpinner, 
        % ThoracolumbarlumbarSpinner_2, ThoracolumbarlumbarSpinner_2
        function EnterButtonPushed(app, event)
            coronalCobb = [app.ProximalThoracicSpinner.Value app.MainThoracicSpinner.Value app.ThoracolumbarlumbarSpinner.Value];
            coronalBendCobb = [app.ProximalThoracicSpinner_2.Value app.MainThoracicSpinner_2.Value app.ThoracolumbarlumbarSpinner_2.Value];
            sagittalCobb = [app.T2T5Spinner.Value app.T5T12Spinner.Value app.T10L2Spinner.Value];
            CoronalCobb_SD = app.CoronalAnglesSpinner.Value;
            SagittalCobb_SD = app.SagittalAnglesSpinner.Value;
            [CT_prob, STM_prob] = probabilisticLenkeClassification(coronalCobb,coronalBendCobb,sagittalCobb,CoronalCobb_SD,SagittalCobb_SD);
            bar(app.UIAxes, CT_prob)
            ylim(app.UIAxes, [0 1])
            bar(app.UIAxes2, STM_prob)
            ylim(app.UIAxes2, [0 1])
            set(app.UIAxes2,'XTickLabel',{'-','N','+'});
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Curve Type')
            ylabel(app.UIAxes, 'Probability')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'Sagittal Thoracic Modifier')
            ylabel(app.UIAxes2, 'Probability')
            app.ProximalThoracicSpinnerLabel = uilabel(app.GridLayout);
            app.ProximalThoracicSpinnerLabel.Text = 'Proximal Thoracic';
            app.ProximalThoracicSpinner = uispinner(app.GridLayout);
            app.ProximalThoracicSpinner.ValueChangingFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.ProximalThoracicSpinner.Limits = [0 180];
            app.ProximalThoracicSpinner.ValueDisplayFormat = '%0.2f°';
            app.ProximalThoracicSpinner.ValueChangedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.MainThoracicSpinnerLabel = uilabel(app.GridLayout);
            app.MainThoracicSpinnerLabel.Text = 'Main Thoracic';
            app.MainThoracicSpinner = uispinner(app.GridLayout);
            app.MainThoracicSpinner.ValueChangingFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.MainThoracicSpinner.Limits = [0 180];
            app.MainThoracicSpinner.ValueDisplayFormat = '%0.2f°';
            app.MainThoracicSpinner.ValueChangedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.ThoracolumbarlumbarSpinnerLabel = uilabel(app.GridLayout);
            app.ThoracolumbarlumbarSpinnerLabel.Text = 'Thoracolumbar/lumbar';
            app.ThoracolumbarlumbarSpinner = uispinner(app.GridLayout);
            app.ThoracolumbarlumbarSpinner.ValueChangingFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.ThoracolumbarlumbarSpinner.Limits = [0 180];
            app.ThoracolumbarlumbarSpinner.ValueDisplayFormat = '%0.2f°';
            app.ThoracolumbarlumbarSpinner.ValueChangedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.ProximalThoracicSpinner_2Label = uilabel(app.GridLayout);
            app.ProximalThoracicSpinner_2Label.Text = 'Proximal Thoracic';
            app.ProximalThoracicSpinner_2 = uispinner(app.GridLayout);
            app.ProximalThoracicSpinner_2.ValueChangingFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.ProximalThoracicSpinner_2.Limits = [0 180];
            app.ProximalThoracicSpinner_2.ValueDisplayFormat = '%0.2f°';
            app.ProximalThoracicSpinner_2.ValueChangedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.MainThoracicSpinner_2Label = uilabel(app.GridLayout);
            app.MainThoracicSpinner_2Label.Text = 'Main Thoracic';
            app.MainThoracicSpinner_2 = uispinner(app.GridLayout);
            app.MainThoracicSpinner_2.ValueChangingFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.MainThoracicSpinner_2.Limits = [0 180];
            app.MainThoracicSpinner_2.ValueDisplayFormat = '%0.2f°';
            app.MainThoracicSpinner_2.ValueChangedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.ThoracolumbarlumbarSpinner_2Label = uilabel(app.GridLayout);
            app.ThoracolumbarlumbarSpinner_2Label.Text = 'Thoracolumbar/lumbar';
            app.ThoracolumbarlumbarSpinner_2 = uispinner(app.GridLayout);
            app.ThoracolumbarlumbarSpinner_2.ValueChangingFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.ThoracolumbarlumbarSpinner_2.Limits = [0 180];
            app.ThoracolumbarlumbarSpinner_2.ValueDisplayFormat = '%0.2f°';
            app.ThoracolumbarlumbarSpinner_2.ValueChangedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.T2T5SpinnerLabel = uilabel(app.GridLayout);
            app.T2T5SpinnerLabel.Text = 'T2-T5';
            app.T2T5Spinner = uispinner(app.GridLayout);
            app.T2T5Spinner.ValueChangingFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.T2T5Spinner.Limits = [0 180];
            app.T2T5Spinner.ValueDisplayFormat = '%0.2f°';
            app.T2T5Spinner.ValueChangedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.T5T12SpinnerLabel = uilabel(app.GridLayout);
            app.T5T12SpinnerLabel.Text = 'T5-T12';
            app.T5T12Spinner = uispinner(app.GridLayout);
            app.T5T12Spinner.ValueChangingFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.T5T12Spinner.Limits = [0 180];
            app.T5T12Spinner.ValueDisplayFormat = '%0.2f°';
            app.T5T12Spinner.ValueChangedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.T10L2SpinnerLabel = uilabel(app.GridLayout);
            app.T10L2SpinnerLabel.Text = 'T10-L2';
            app.T10L2Spinner = uispinner(app.GridLayout);
            app.T10L2Spinner.ValueChangingFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.T10L2Spinner.Limits = [0 180];
            app.T10L2Spinner.ValueDisplayFormat = '%0.2f°';
            app.T10L2Spinner.ValueChangedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.ProbabilisticLenkeClassificationPLCLabel = uilabel(app.GridLayout);
            app.ProbabilisticLenkeClassificationPLCLabel.Text = 'Probabilistic Lenke Classification (PLC)';
            app.CobbAnglesLabel = uilabel(app.GridLayout);
            app.CobbAnglesLabel.Text = 'Cobb Angles';
            app.BendingFilmLabel = uilabel(app.GridLayout);
            app.BendingFilmLabel.Text = 'Bending Film';
            app.KyphosisLabel = uilabel(app.GridLayout);
            app.KyphosisLabel.Text = 'Kyphosis';
            app.EnterButton = uibutton(app.GridLayout, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.NoteTextAreaLabel = uilabel(app.GridLayout);
            app.NoteTextAreaLabel.Text = 'Note:';
            app.NoteTextArea = uitextarea(app.GridLayout);
            app.NoteTextArea.Value = {'A standard deviation of approximately 3° is commonly observered in the literature.'};
            app.InterobserverStandardDeviationLabel = uilabel(app.GridLayout);
            app.InterobserverStandardDeviationLabel.Text = 'Interobserver Standard Deviation';
            app.CoronalAnglesSpinnerLabel = uilabel(app.GridLayout);
            app.CoronalAnglesSpinnerLabel.Text = 'Coronal Angles';
            app.CoronalAnglesSpinner = uispinner(app.GridLayout);
            app.CoronalAnglesSpinner.ValueChangingFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.CoronalAnglesSpinner.ValueDisplayFormat = '%0.2f°';
            app.CoronalAnglesSpinner.ValueChangedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.SagittalAnglesSpinnerLabel = uilabel(app.GridLayout);
            app.SagittalAnglesSpinnerLabel.Text = 'Sagittal Angles';
            app.SagittalAnglesSpinner = uispinner(app.GridLayout);
            app.SagittalAnglesSpinner.ValueChangingFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.SagittalAnglesSpinner.ValueDisplayFormat = '%0.2f°';
            app.SagittalAnglesSpinner.ValueChangedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PLC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end